classdef interface_chooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioConfigFigure      matlab.ui.Figure
        OutputDeviceDropDown   matlab.ui.control.DropDown
        OutputStepLabel        matlab.ui.control.Label
        OutputDeviceLabel      matlab.ui.control.Label
        OutputTestLabel        matlab.ui.control.Label
        OutputTestLeftButton   matlab.ui.control.Button
        OutputTestRightButton  matlab.ui.control.Button
        RequerimentsLabel      matlab.ui.control.Label
        InputDeviceDropDown    matlab.ui.control.DropDown
        InputStepLabel         matlab.ui.control.Label
        InputDeviceLabel       matlab.ui.control.Label
        TestLabel              matlab.ui.control.Label
        TestSignalAxes         matlab.ui.control.UIAxes
        ReloadButton           matlab.ui.control.Button
        DoneButton             matlab.ui.control.Button
        TestInstructionsLabel  matlab.ui.control.Label
        TestButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        OutputDevices % Description
        InputDevices % Description
    end
    
    properties (Access = public)
        DoneCallback % Description
    end
    
    methods (Access = private)
        
        function loadDevices(app)
            info = audiodevinfo;
            app.InputDevices = info.input;
            app.OutputDevices = info.output;
            
            app.InputDeviceDropDown.Items = {info.input.Name};
            app.InputDeviceDropDown.ItemsData = {info.input.ID};
            app.OutputDeviceDropDown.Items = {info.output.Name};
            app.OutputDeviceDropDown.ItemsData = {info.output.ID};
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loadDevices
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.loadDevices
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.DoneButton.Enable = false;

            % Generate test signal
            Fs = 48000; nBits = 16;
            silence_samples = Fs * 1;
            noise_samples = Fs * 1.5;
            noise_amplitude = 0.75;
            signal = test_signal(silence_samples, noise_samples, noise_amplitude);
            signal = [ signal; zeros(1, length(signal)) ];

            % Send
            d = uiprogressdlg(app.AudioConfigFigure, 'Indeterminate', 'on', 'Message', 'Sending test signal...', 'Title', 'Loopback test');
            try
                in = raw_acquire(signal', Fs, nBits, app.OutputDeviceDropDown.Value, app.InputDeviceDropDown.Value)';
                in = in(1, :);
            catch err
                close(d);
                uialert(app.AudioConfigFigure, ...
                    sprintf("There was a problem when playing / recording:\n%s", err.message), ...
                    "Loopback test error", ...
                    'Icon', 'error', 'Modal', true);
                return
            end
            close(d);

            % Plot and test
            [latency, ok] = test_signal_verify(in, silence_samples, noise_samples, noise_amplitude);
            plot(app.TestSignalAxes, (0:length(in)-1)/Fs, in);
            xlim(app.TestSignalAxes, [0 length(in)-1] / Fs);
            hold(app.TestSignalAxes, 'on');
            plot(app.TestSignalAxes, latency/Fs * [1 1], [-1,1], 'r', 'LineWidth', 1);
            plot(app.TestSignalAxes, xlim(app.TestSignalAxes), noise_amplitude * [1 1], 'b-');
            plot(app.TestSignalAxes, xlim(app.TestSignalAxes), -noise_amplitude * [1 1], 'b');
            hold(app.TestSignalAxes, 'off');
            
            if ~ok
                uialert(app.AudioConfigFigure, ...
                    sprintf("Unexpected input signal. Make sure you selected the correct devices, connected the correct channel and the input and output volumes are correct so the input signal doesn't saturate."), ...
                    "Loopback test failure", ...
                    'Icon', 'warning', 'Modal', true);
                return
            end
            app.DoneButton.Enable = true;
        end

        % Button pushed function: OutputTestLeftButton
        function OutputTestLeftButtonPushed(app, event)
            Fs = 48000; nBits = 16;
            y = 0.8 * sin(2*pi*440/Fs * (1:Fs*2));
            z = zeros(1, length(y));
            try
                player = audioplayer([y; z]', Fs, nBits, app.OutputDeviceDropDown.Value);
                play(player);
            catch err
                uialert(app.AudioConfigFigure, ...
                    sprintf("There was a problem when playing:\n%s", err.message), ...
                    "Playback error", ...
                    'Icon', 'error', 'Modal', true);
                return
            end
            d = uiprogressdlg(app.AudioConfigFigure, 'Indeterminate', 'on', 'Message', 'Playing right channel sample...', 'Title', 'Playback test');
            pause(length(y) / Fs);
            close(d);
        end

        % Button pushed function: OutputTestRightButton
        function OutputTestRightButtonPushed(app, event)
            Fs = 48000; nBits = 16;
            y = 0.8 * sin(2*pi*440/Fs * (1:Fs*2));
            z = zeros(1, length(y));
            try
                player = audioplayer([z; y]', Fs, nBits, app.OutputDeviceDropDown.Value);
                play(player);
            catch err
                uialert(app.AudioConfigFigure, ...
                    sprintf("There was a problem when playing:\n%s", err.message), ...
                    "Playback error", ...
                    'Icon', 'error', 'Modal', true);
                return
            end
            d = uiprogressdlg(app.AudioConfigFigure, 'Indeterminate', 'on', 'Message', 'Playing right channel sample...', 'Title', 'Playback test');
            pause(length(y) / Fs);
            close(d);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            setpref("virtualinstruments", "outputID", app.OutputDeviceDropDown.Value);
            setpref("virtualinstruments", "inputID", app.InputDeviceDropDown.Value);
            app.delete;
            analyzer;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioConfigFigure = uifigure;
            app.AudioConfigFigure.Name = 'Audio interfaces';
            app.OutputDeviceDropDown = uidropdown(app.AudioConfigFigure);
            app.OutputDeviceDropDown.Tooltip = {'Audio device to use for playback.'};
            app.OutputStepLabel = uilabel(app.AudioConfigFigure);
            app.OutputStepLabel.Text = 'Step 1. Select output device (DAC)';
            app.OutputDeviceLabel = uilabel(app.AudioConfigFigure);
            app.OutputDeviceLabel.Text = 'Device';
            app.OutputTestLabel = uilabel(app.AudioConfigFigure);
            app.OutputTestLabel.Text = 'Test';
            app.OutputTestLeftButton = uibutton(app.AudioConfigFigure, 'push');
            app.OutputTestLeftButton.ButtonPushedFcn = createCallbackFcn(app, @OutputTestLeftButtonPushed, true);
            app.OutputTestLeftButton.Icon = 'media-play.png';
            app.OutputTestLeftButton.Tooltip = {'Play 4 seconds of a 440Hz tone on the left channel.'};
            app.OutputTestLeftButton.Text = 'Play left';
            app.OutputTestRightButton = uibutton(app.AudioConfigFigure, 'push');
            app.OutputTestRightButton.ButtonPushedFcn = createCallbackFcn(app, @OutputTestRightButtonPushed, true);
            app.OutputTestRightButton.Icon = 'media-play.png';
            app.OutputTestRightButton.Tooltip = {'Play 4 seconds of a 440Hz tone on the right channel.'};
            app.OutputTestRightButton.Text = 'Play right';
            app.RequerimentsLabel = uilabel(app.AudioConfigFigure);
            app.RequerimentsLabel.Text = {'For proper app operation please select the audio interfaces to use.'; 'Make sure the selected devices support 16-bit stereo at 48kHz.'};
            app.InputDeviceDropDown = uidropdown(app.AudioConfigFigure);
            app.InputDeviceDropDown.Tooltip = {'Audio device to use for recording.'};
            app.InputStepLabel = uilabel(app.AudioConfigFigure);
            app.InputStepLabel.Text = 'Step 2. Select input device (ADC)';
            app.InputDeviceLabel = uilabel(app.AudioConfigFigure);
            app.InputDeviceLabel.Text = 'Device';
            app.TestLabel = uilabel(app.AudioConfigFigure);
            app.TestLabel.Text = 'Step 3. Test setup';
            app.TestSignalAxes = uiaxes(app.AudioConfigFigure);
            title(app.TestSignalAxes, '')
            xlabel(app.TestSignalAxes, 'time [s]')
            ylabel(app.TestSignalAxes, '')
            app.TestSignalAxes.YLim = [-1 1];
            app.TestSignalAxes.YTick = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.ReloadButton = uibutton(app.AudioConfigFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Icon = 'reload.png';
            app.ReloadButton.Tooltip = {'Query system for available audio devices.'};
            app.ReloadButton.Text = 'Reload devices';
            app.DoneButton = uibutton(app.AudioConfigFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Icon = 'check.png';
            app.DoneButton.Enable = 'off';
            app.DoneButton.Tooltip = {'Apply changes.'};
            app.DoneButton.Text = 'Done';
            app.TestInstructionsLabel = uilabel(app.AudioConfigFigure);
            app.TestInstructionsLabel.Text = {'Connect the left channel of the DAC and ADC through the amplifier to'; 'measure. Then send the test signal, and the input will be plotted below.'; 'You should see a period of silence, and then a period of noise. Adjust'; 'ADC and DAC gains so that noise amplitude is about 0.75.'};
            app.TestButton = uibutton(app.AudioConfigFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Icon = 'media-record.png';
            app.TestButton.Text = 'Test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_chooser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioConfigFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioConfigFigure)
        end
    end
end