classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResponseAxes                 matlab.ui.control.UIAxes
        InputfilenameEditFieldLabel  matlab.ui.control.Label
        InputfilenameEditField       matlab.ui.control.EditField
        ConversionLabel              matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        ConvertButton                matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Slider_1                     matlab.ui.control.Slider
        Slider_2                     matlab.ui.control.Slider
        Slider_3                     matlab.ui.control.Slider
        Slider_4                     matlab.ui.control.Slider
        Slider_5                     matlab.ui.control.Slider
        Slider_6                     matlab.ui.control.Slider
        Slider_7                     matlab.ui.control.Slider
        Slider_8                     matlab.ui.control.Slider
        Slider_9                     matlab.ui.control.Slider
        Slider_10                    matlab.ui.control.Slider
        GainsLabel                   matlab.ui.control.Label
        CenterHzLabel                matlab.ui.control.Label
        BandwidthLabel               matlab.ui.control.Label
        CenterField_1                matlab.ui.control.NumericEditField
        CenterField_2                matlab.ui.control.NumericEditField
        CenterField_3                matlab.ui.control.NumericEditField
        CenterField_4                matlab.ui.control.NumericEditField
        CenterField_5                matlab.ui.control.NumericEditField
        CenterField_6                matlab.ui.control.NumericEditField
        CenterField_7                matlab.ui.control.NumericEditField
        CenterField_8                matlab.ui.control.NumericEditField
        CenterField_9                matlab.ui.control.NumericEditField
        CenterField_10               matlab.ui.control.NumericEditField
        BandwidthField_1             matlab.ui.control.NumericEditField
        BandwidthField_2             matlab.ui.control.NumericEditField
        BandwidthField_3             matlab.ui.control.NumericEditField
        BandwidthField_4             matlab.ui.control.NumericEditField
        BandwidthField_5             matlab.ui.control.NumericEditField
        BandwidthField_6             matlab.ui.control.NumericEditField
        BandwidthField_7             matlab.ui.control.NumericEditField
        BandwidthField_8             matlab.ui.control.NumericEditField
        BandwidthField_9             matlab.ui.control.NumericEditField
        BandwidthField_10            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Fs = 48000
        Gains
        Freqs
        Bandwidths
        sos
    end
    
    methods (Access = private)
        
        function gainChanged(app, idx, value)
            app.Gains(idx) = value;
            app.sos(idx, :) = coef_peak(app.Freqs(idx), app.Bandwidths(idx), app.Gains(idx), app.Fs);
            app.regenerateFilter;
        end
    
        function centerChanged(app, idx, value)
            app.Freqs(idx) = value;
            app.sos(idx, :) = coef_peak(app.Freqs(idx), app.Bandwidths(idx), app.Gains(idx), app.Fs);
            app.regenerateFilter;
        end
    
        function bandwidthChanged(app, idx, value)
            app.Bandwidths(idx) = value;
            app.sos(idx, :) = coef_peak(app.Freqs(idx), app.Bandwidths(idx), app.Gains(idx), app.Fs);
            app.regenerateFilter;
        end
    
        function regenerateFilter(app)
            imp = impu_coef(app.sos);
            plot_EQ(app.ResponseAxes, app.Freqs, app.Bandwidths, imp, app.Fs);
        end
        function updateAllFilters(app)
            centerFields = [ app.CenterField_1 app.CenterField_2 app.CenterField_3 app.CenterField_4 app.CenterField_5 app.CenterField_6 app.CenterField_7 app.CenterField_8 app.CenterField_9 app.CenterField_10 ];
            bwFields = [ app.BandwidthField_1 app.BandwidthField_2 app.BandwidthField_3 app.BandwidthField_4 app.BandwidthField_5 app.BandwidthField_6 app.BandwidthField_7 app.BandwidthField_8 app.BandwidthField_9 app.BandwidthField_10 ];
            for idx = (1:length(app.Gains))
                app.sos(idx, :) = coef_peak(app.Freqs(idx), app.Bandwidths(idx), app.Gains(idx), app.Fs);
                centerFields(idx).Value = app.Freqs(idx);
                bwFields(idx).Value = app.Bandwidths(idx);
            end
            app.regenerateFilter;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Gains = zeros(1, 10);
            app.Freqs = [31.25 62.5 125 250 500 1000 2000 4000 8000 16000];
            app.Bandwidths = app.Freqs * (sqrt(2) - 1/sqrt(2));
            app.sos = zeros(length(app.Gains), 6);
            app.updateAllFilters;
        end

        % Value changed function: Slider_1
        function Slider_1_1ValueChanged(app, event)
            app.gainChanged(1, app.Slider_1.Value);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.gainChanged(2, app.Slider_2.Value);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.gainChanged(3, app.Slider_3.Value);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.gainChanged(4, app.Slider_4.Value);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.gainChanged(5, app.Slider_5.Value);
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            app.gainChanged(6, app.Slider_6.Value);
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            app.gainChanged(7, app.Slider_7.Value);
        end

        % Value changed function: Slider_8
        function Slider_8ValueChanged(app, event)
            app.gainChanged(8, app.Slider_8.Value);
        end

        % Value changed function: Slider_9
        function Slider_9ValueChanged(app, event)
            app.gainChanged(9, app.Slider_9.Value);
        end

        % Value changed function: Slider_10
        function Slider_10ValueChanged(app, event)
            app.gainChanged(10, app.Slider_10.Value)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile("*");
            app.InputfilenameEditField.Value = path + file;
            app.ConvertButton.Enable = true;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            [x, Fs] = audioread(app.InputfilenameEditField.Value);
            sos = zeros(length(app.Gains), 6);
            for idx = (1:length(app.Gains))
                sos(idx, :) = coef_peak(app.Freqs(idx), app.Bandwidths(idx), app.Gains(idx), Fs);
            end
            y = filtrar_sos(sos, x);
            audiowrite('converted.wav', y, Fs);
        end

        % Value changed function: CenterField_1
        function CenterField_1ValueChanged(app, event)
            app.centerChanged(1, app.CenterField_1.Value);
        end

        % Value changed function: CenterField_2
        function CenterField_2ValueChanged(app, event)
            app.centerChanged(2, app.CenterField_2.Value);
        end

        % Value changed function: CenterField_3
        function CenterField_3ValueChanged(app, event)
            app.centerChanged(3, app.CenterField_3.Value);
        end

        % Value changed function: CenterField_4
        function CenterField_4ValueChanged(app, event)
            app.centerChanged(4, app.CenterField_4.Value);
        end

        % Value changed function: CenterField_5
        function CenterField_5ValueChanged(app, event)
            app.centerChanged(5, app.CenterField_5.Value);
        end

        % Value changed function: CenterField_6
        function CenterField_6ValueChanged(app, event)
            app.centerChanged(6, app.CenterField_6.Value);
        end

        % Value changed function: CenterField_7
        function CenterField_7ValueChanged(app, event)
            app.centerChanged(7, app.CenterField_7.Value);
        end

        % Value changed function: CenterField_8
        function CenterField_8ValueChanged(app, event)
            app.centerChanged(8, app.CenterField_8.Value);
        end

        % Value changed function: CenterField_9
        function CenterField_9ValueChanged(app, event)
            app.centerChanged(9, app.CenterField_9.Value);
        end

        % Value changed function: CenterField_10
        function CenterField_10ValueChanged(app, event)
            app.centerChanged(10, app.CenterField_10.Value);
        end

        % Value changed function: BandwidthField_1
        function BandwidthField_1ValueChanged(app, event)
            app.bandwidthChanged(1, app.BandwidthField_1.Value);
        end

        % Value changed function: BandwidthField_2
        function BandwidthField_2ValueChanged(app, event)
            app.bandwidthChanged(2, app.BandwidthField_2.Value);
        end

        % Value changed function: BandwidthField_3
        function BandwidthField_3ValueChanged(app, event)
            app.bandwidthChanged(3, app.BandwidthField_3.Value);
        end

        % Value changed function: BandwidthField_4
        function BandwidthField_4ValueChanged(app, event)
            app.bandwidthChanged(4, app.BandwidthField_4.Value);
        end

        % Value changed function: BandwidthField_5
        function BandwidthField_5ValueChanged(app, event)
            app.bandwidthChanged(5, app.BandwidthField_5.Value);
        end

        % Value changed function: BandwidthField_6
        function BandwidthField_6ValueChanged(app, event)
            app.bandwidthChanged(6, app.BandwidthField_6.Value);
        end

        % Value changed function: BandwidthField_7
        function BandwidthField_7ValueChanged(app, event)
            app.bandwidthChanged(7, app.BandwidthField_7.Value);
        end

        % Value changed function: BandwidthField_8
        function BandwidthField_8ValueChanged(app, event)
            app.bandwidthChanged(8, app.BandwidthField_8.Value);
        end

        % Value changed function: BandwidthField_9
        function BandwidthField_9ValueChanged(app, event)
            app.bandwidthChanged(9, app.BandwidthField_9.Value);
        end

        % Value changed function: BandwidthField_10
        function BandwidthField_10ValueChanged(app, event)
            app.bandwidthChanged(10, app.BandwidthField_10.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Equalizer | Virtual instruments';
            app.ResponseAxes = uiaxes(app.UIFigure);
            xlabel(app.ResponseAxes, 'X')
            ylabel(app.ResponseAxes, 'Y')
            app.InputfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.InputfilenameEditFieldLabel.Text = 'Input filename:';
            app.InputfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.ConversionLabel = uilabel(app.UIFigure);
            app.ConversionLabel.Text = 'Conversion';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'file.png';
            app.BrowseButton.Text = 'Browse';
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Enable = 'off';
            app.ConvertButton.Text = 'Convert';
            app.Panel = uipanel(app.UIFigure);
            app.Slider_1 = uislider(app.Panel);
            app.Slider_1.Limits = [-10 10];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1_1ValueChanged, true);
            app.Slider_2 = uislider(app.Panel);
            app.Slider_2.Limits = [-10 10];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.Panel);
            app.Slider_3.Limits = [-10 10];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.Panel);
            app.Slider_4.Limits = [-10 10];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5 = uislider(app.Panel);
            app.Slider_5.Limits = [-10 10];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_6 = uislider(app.Panel);
            app.Slider_6.Limits = [-10 10];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_7 = uislider(app.Panel);
            app.Slider_7.Limits = [-10 10];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_8 = uislider(app.Panel);
            app.Slider_8.Limits = [-10 10];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @Slider_8ValueChanged, true);
            app.Slider_9 = uislider(app.Panel);
            app.Slider_9.Limits = [-10 10];
            app.Slider_9.ValueChangedFcn = createCallbackFcn(app, @Slider_9ValueChanged, true);
            app.Slider_10 = uislider(app.Panel);
            app.Slider_10.Limits = [-10 10];
            app.Slider_10.ValueChangedFcn = createCallbackFcn(app, @Slider_10ValueChanged, true);
            app.GainsLabel = uilabel(app.Panel);
            app.GainsLabel.Text = 'Gains';
            app.CenterHzLabel = uilabel(app.Panel);
            app.CenterHzLabel.Text = 'Center [Hz]';
            app.BandwidthLabel = uilabel(app.Panel);
            app.BandwidthLabel.Text = 'Bandwidth';
            app.CenterField_1 = uieditfield(app.Panel, 'numeric');
            app.CenterField_1.ValueDisplayFormat = '%.0f';
            app.CenterField_1.ValueChangedFcn = createCallbackFcn(app, @CenterField_1ValueChanged, true);
            app.CenterField_1.Value = 10000;
            app.CenterField_2 = uieditfield(app.Panel, 'numeric');
            app.CenterField_2.ValueDisplayFormat = '%.0f';
            app.CenterField_2.ValueChangedFcn = createCallbackFcn(app, @CenterField_2ValueChanged, true);
            app.CenterField_2.Value = 10000;
            app.CenterField_3 = uieditfield(app.Panel, 'numeric');
            app.CenterField_3.ValueDisplayFormat = '%.0f';
            app.CenterField_3.ValueChangedFcn = createCallbackFcn(app, @CenterField_3ValueChanged, true);
            app.CenterField_3.Value = 10000;
            app.CenterField_4 = uieditfield(app.Panel, 'numeric');
            app.CenterField_4.ValueDisplayFormat = '%.0f';
            app.CenterField_4.ValueChangedFcn = createCallbackFcn(app, @CenterField_4ValueChanged, true);
            app.CenterField_4.Value = 10000;
            app.CenterField_5 = uieditfield(app.Panel, 'numeric');
            app.CenterField_5.ValueDisplayFormat = '%.0f';
            app.CenterField_5.ValueChangedFcn = createCallbackFcn(app, @CenterField_5ValueChanged, true);
            app.CenterField_5.Value = 10000;
            app.CenterField_6 = uieditfield(app.Panel, 'numeric');
            app.CenterField_6.ValueDisplayFormat = '%.0f';
            app.CenterField_6.ValueChangedFcn = createCallbackFcn(app, @CenterField_6ValueChanged, true);
            app.CenterField_6.Value = 10000;
            app.CenterField_7 = uieditfield(app.Panel, 'numeric');
            app.CenterField_7.ValueDisplayFormat = '%.0f';
            app.CenterField_7.ValueChangedFcn = createCallbackFcn(app, @CenterField_7ValueChanged, true);
            app.CenterField_7.Value = 10000;
            app.CenterField_8 = uieditfield(app.Panel, 'numeric');
            app.CenterField_8.ValueDisplayFormat = '%.0f';
            app.CenterField_8.ValueChangedFcn = createCallbackFcn(app, @CenterField_8ValueChanged, true);
            app.CenterField_8.Value = 10000;
            app.CenterField_9 = uieditfield(app.Panel, 'numeric');
            app.CenterField_9.ValueDisplayFormat = '%.0f';
            app.CenterField_9.ValueChangedFcn = createCallbackFcn(app, @CenterField_9ValueChanged, true);
            app.CenterField_9.Value = 10000;
            app.CenterField_10 = uieditfield(app.Panel, 'numeric');
            app.CenterField_10.ValueDisplayFormat = '%.0f';
            app.CenterField_10.ValueChangedFcn = createCallbackFcn(app, @CenterField_10ValueChanged, true);
            app.CenterField_10.Value = 10000;
            app.BandwidthField_1 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_1.ValueDisplayFormat = '%.0f';
            app.BandwidthField_1.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_1ValueChanged, true);
            app.BandwidthField_1.Value = 10000;
            app.BandwidthField_2 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_2.ValueDisplayFormat = '%.0f';
            app.BandwidthField_2.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_2ValueChanged, true);
            app.BandwidthField_2.Value = 10000;
            app.BandwidthField_3 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_3.ValueDisplayFormat = '%.0f';
            app.BandwidthField_3.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_3ValueChanged, true);
            app.BandwidthField_3.Value = 10000;
            app.BandwidthField_4 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_4.ValueDisplayFormat = '%.0f';
            app.BandwidthField_4.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_4ValueChanged, true);
            app.BandwidthField_4.Value = 10000;
            app.BandwidthField_5 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_5.ValueDisplayFormat = '%.0f';
            app.BandwidthField_5.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_5ValueChanged, true);
            app.BandwidthField_5.Value = 10000;
            app.BandwidthField_6 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_6.ValueDisplayFormat = '%.0f';
            app.BandwidthField_6.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_6ValueChanged, true);
            app.BandwidthField_6.Value = 10000;
            app.BandwidthField_7 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_7.ValueDisplayFormat = '%.0f';
            app.BandwidthField_7.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_7ValueChanged, true);
            app.BandwidthField_7.Value = 10000;
            app.BandwidthField_8 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_8.ValueDisplayFormat = '%.0f';
            app.BandwidthField_8.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_8ValueChanged, true);
            app.BandwidthField_8.Value = 10000;
            app.BandwidthField_9 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_9.ValueDisplayFormat = '%.0f';
            app.BandwidthField_9.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_9ValueChanged, true);
            app.BandwidthField_9.Value = 10000;
            app.BandwidthField_10 = uieditfield(app.Panel, 'numeric');
            app.BandwidthField_10.ValueDisplayFormat = '%.0f';
            app.BandwidthField_10.ValueChangedFcn = createCallbackFcn(app, @BandwidthField_10ValueChanged, true);
            app.BandwidthField_10.Value = 10000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end